classdef CCU < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DelayGeneratorStatusPanel      matlab.ui.container.Panel
        ImagingModeEditFieldLabel      matlab.ui.control.Label
        ImagingMode                    matlab.ui.control.EditField
        DelayGenerationLabel           matlab.ui.control.Label
        WaveGen                        matlab.ui.control.EditField
        HzPortLabel                    matlab.ui.control.Label
        Frequency                      matlab.ui.control.EditField
        IO1PortLabel                   matlab.ui.control.Label
        Delay1                         matlab.ui.control.EditField
        IO2PortLabel                   matlab.ui.control.Label
        Delay2                         matlab.ui.control.EditField
        IO3PortLabel                   matlab.ui.control.Label
        Delay3                         matlab.ui.control.EditField
        IO4PortLabel                   matlab.ui.control.Label
        Delay4                         matlab.ui.control.EditField
        TriggerOutDelayLabel           matlab.ui.control.Label
        TriggerOutDly                  matlab.ui.control.EditField
        TriggerIN1EditFieldLabel       matlab.ui.control.Label
        TriggerIN1                     matlab.ui.control.EditField
        TriggerIN2EditFieldLabel       matlab.ui.control.Label
        TriggerIN2                     matlab.ui.control.EditField
        HighVoltagePowerSuppliesPanel  matlab.ui.container.Panel
        VNNknob                        matlab.ui.control.Knob
        VNNLabel                       matlab.ui.control.Label
        VNNlamp                        matlab.ui.control.Lamp
        VPPknob                        matlab.ui.control.Knob
        VPPLabel                       matlab.ui.control.Label
        VPPlamp                        matlab.ui.control.Lamp
        VNNreadVEditField_2Label       matlab.ui.control.Label
        VNNread                        matlab.ui.control.NumericEditField
        VPPreadVEditFieldLabel         matlab.ui.control.Label
        VPPread                        matlab.ui.control.NumericEditField
        Switch                         matlab.ui.control.Switch
        ParallelLabel                  matlab.ui.control.Label
        STOP                           matlab.ui.control.StateButton
        PanelCardsStatus               matlab.ui.container.Panel
        PanelCard1                     matlab.ui.container.Panel
        Card1Label                     matlab.ui.control.Label
        Card1Connect                   matlab.ui.control.Lamp
        Card1VNN                       matlab.ui.control.NumericEditField
        Card1VPP                       matlab.ui.control.NumericEditField
        ConnectionLabel                matlab.ui.control.Label
        VNNLabel_2                     matlab.ui.control.Label
        PanelCard2                     matlab.ui.container.Panel
        Card2Label                     matlab.ui.control.Label
        Card2Connect                   matlab.ui.control.Lamp
        Card2VNN                       matlab.ui.control.NumericEditField
        Card2VPP                       matlab.ui.control.NumericEditField
        VPPLabel_2                     matlab.ui.control.Label
        PanelCard3                     matlab.ui.container.Panel
        Card3Label                     matlab.ui.control.Label
        Card3Connect                   matlab.ui.control.Lamp
        Card3VNN                       matlab.ui.control.NumericEditField
        Card3VPP                       matlab.ui.control.NumericEditField
        PanelCard4                     matlab.ui.container.Panel
        Card4Label                     matlab.ui.control.Label
        Card4Connect                   matlab.ui.control.Lamp
        Card4VNN                       matlab.ui.control.NumericEditField
        Card4VPP                       matlab.ui.control.NumericEditField
        MSG                            matlab.ui.control.Label
        OnlyreprogramPSsButton         matlab.ui.control.Button
        ReprogramButtonall             matlab.ui.control.Button
    end


    properties (Access = private) %Variables shared between all modules
        SerialPort=''; 
        opendSerial='';
        inputvariables=0;
        timr;
        timerstatus=0;
        timepassedONcount;
        timepassedON;
       
 
        %input Parameters
        input; %Structure containing all settings
        DataRev;
        
       
    end

    methods (Access = private)
    
          % Code that executes after component creation
          function repeatingTimer(app)
              app.timr = timer('StartDelay', 0, 'Period', app.input.TimerInterval,'ExecutionMode', 'fixedRate');
              app.timr.TimerFcn = @(obj,event)timercallback(app);
              if app.timerstatus==0
              start(app.timr);
              app.timerstatus=1;
              end
              
          end
          
          function timercallback(app) % this app will be called by the timer repeatedly

                        
              if app.input.intiation==0  % means this is the first time running app, setup CCU
              app.updateDisplay;
                 %app.input.COM='AUTO';
                  app.DataRev = COMfunction(app.input); %transfer all settings to CCU and excte them on the board
app.input.Operation=app.DataRev.Operation;
                   app.input.intiation=app.DataRev.intiation;   
                   if app.input.intiation==1

                 %  app.input.COM=app.DataRev.COM;
                       s = serial(app.input.COM);  % Open the port
                       s.BaudRate = 9600;
                       newobjs = instrfind;
                       fclose(newobjs)
                       fopen(s);
                      app.input.sending=s;   
                      
                   end


              else                       % means we already setup CCU, just recevie data
                  app.MSG.Text=['Running OK! Last update at ' datestr(now,'HH:MM:SS')] ;
                %  app.OnlyreprogramPSsButton.Visible='off';
                
                  app.DataRev = COMfunction(app.input); %transfer all settings to CCU but do not excute, just receive update
                  app.input.intiation=app.DataRev.intiation;
                  app.input.Operation=app.DataRev.Operation;
              end
              if app.DataRev.STOP==1
                  app.ReprogramButtonall.Enable='on';
                  app.OnlyreprogramPSsButton.Enable='on';
                  if app.DataRev.Trigger2==0
                      app.MSG.Text=['VEE ERROR, check Power Supply'] ;
                  elseif app.DataRev.Trigger1==1
                      if(app.DataRev.VNNccu>-5&&app.input.VNN<-5)||(app.DataRev.VPPccu<5&&app.input.VPP>5)
                      %app.OnlyreprogramPSsButton.Visible='on';
                      app.MSG.Text=['PS Error!'] ;
                      else
                      app.MSG.Text=['ERROR! HV not in the range'] ;
                      end
                  else
                      app.MSG.Text=['Process paused!'] ;
                  end
                  app.STOP.Value=true;
                  app.input.STOP=1;
              else
                  app.ReprogramButtonall.Enable='off';
                  app.OnlyreprogramPSsButton.Enable='off';
                  app.STOP.Value=false;
                 % app.input.STOP=0;
              end
    
       
              app.timepassedONcount=app.timepassedONcount+1;
              if (app.DataRev.STOP==1)
                app.timepassedONcount=0;
              end
              app.timepassedON=app.input.TimerInterval*app.timepassedONcount;
              
              if (app.DataRev.VPPccu>100||app.DataRev.VPPccu<-100)
                  if(app.timepassedON>60*250/(abs(app.DataRev.VPPccu)+abs(app.DataRev.VNNccu)))
                       display('Warning: ON time is getting too long');
                       app.timepassedONcount=0;
                  end
%                   
              end

           app.updateDisplay;   
          end


          function updateDisplay(app) %updates the display, anything here will be update with the lastes changes

              
              if app.input.intiation==0  % means this is the first time running app, setup based on input
                 
                  if app.input.ImgMode== "US"
                     app.ImagingMode.Value = 'Ultrasound';  
%                      app.TriggerIN1.Value = 'Disabled'; 
                     %app.TriggerIN1.BackgroundColor='y';
                 elseif app.input.ImgMode == "PA"
                     app.ImagingMode.Value = 'Photoacoustic'; 
%                      app.TriggerIN1.Value = 'Disabled'; 
%                      app.TriggerIN1.BackgroundColor='y';
                  else
                 app.ImagingMode.Value = 'Fix this';      
                 app.ImagingMode.BackgroundColor='r';
                 app.AutoEMERGENCYstop('Wrong Input');    
                 app.STOP.Enable='off';
                  end


%                      app.TriggerIN2.Value = 'VSX Tr Out'; 
%                      app.TriggerIN2.BackgroundColor='g';

                  
                  if isnumeric(app.input.TrOd)
                  if app.input.TrOd== 0
                     app.TriggerOutDly.Value = 'Auto';  
                     app.TriggerOutDly.BackgroundColor='g';
                  else 
                    if app.input.ImgMode== "US"  
                     app.TriggerOutDly.Value = [num2str(app.input.TrOd) ' us']; 
                    else
                     app.TriggerOutDly.Value = [num2str(300) ' us']; 
                    end
                     app.TriggerOutDly.BackgroundColor='g';
                  end
                  else
                  app.TriggerOutDly.Value = 'Fix this';      
                  app.TriggerOutDly.BackgroundColor='r';
                  app.AutoEMERGENCYstop('Wrong Input');     
                  app.STOP.Enable='off';
                  end       
                  
                  if app.input.ImgMode == "PA"
                  if isnumeric(app.input.Qswitch)  && app.input.Qswitch>300 && app.input.Qswitch<600
                     app.WaveGen.Value = [num2str(app.input.Qswitch) ' qSwitch'];  
                     app.WaveGen.BackgroundColor='g';
                     app.Frequency.Value = 'Laser 10 Hz';  
                     app.Frequency.BackgroundColor='g';   
                     app.Delay1.Value = 'Laser qSwitch';  
                     app.Delay1.BackgroundColor='g'; 
                  else 
                     app.WaveGen.Value = 'Fix this'; 
                     app.WaveGen.BackgroundColor='r';
                     app.AutoEMERGENCYstop('Wrong Input');    
                     app.STOP.Enable='off';
                  end
                  end 
                  
                  if app.input.delay2.En==1
                      if isnumeric(app.input.delay2.IniD) && isnumeric(app.input.delay2.lengthD)&& isnumeric(app.input.delay2.Inv)
                     app.Delay2.Value = 'VSX Tr In 1'; 
                     app.Delay2.BackgroundColor='g';
                      else
                     app.Delay2.Value = 'Fix this'; 
                     app.Delay2.BackgroundColor='r';
                     app.AutoEMERGENCYstop('Wrong Input');    
                     app.STOP.Enable='off';
                      end
                  end
                  
                  if app.input.delay3.En==1
                      if isnumeric(app.input.delay3.IniD) && isnumeric(app.input.delay3.lengthD)&& isnumeric(app.input.delay3.Inv)
                     app.Delay3.Value = 'PD 10 Hz'; 
                     app.Delay3.BackgroundColor='g';
                      else
                     app.Delay3.Value = 'Fix this'; 
                     app.Delay3.BackgroundColor='r';
                     app.AutoEMERGENCYstop('Wrong Input');    
                     app.STOP.Enable='off';
                      end
                  end                 
                  if app.input.delay4.En==1
                      if isnumeric(app.input.delay4.IniD) && isnumeric(app.input.delay4.lengthD)&& isnumeric(app.input.delay4.Inv)
                     app.Delay4.Value = 'TrO'; 
                     app.Delay4.BackgroundColor='g';
                      else
                     app.Delay4.Value = 'Fix this'; 
                     app.Delay4.BackgroundColor='r';
                     app.AutoEMERGENCYstop('Wrong Input');    
                     app.STOP.Enable='off';
                      end
                  end      
                  
                  if isnumeric(app.input.VNN) && app.input.VNN<0 %check voltage limit
                      app.VNNknob.Value=app.input.VNN;
                  else
                     app.VNNknob.Enable='off';
                     app.VNNlamp.Enable ='off';
                     app.AutoEMERGENCYstop('Wrong Input');    
                     app.STOP.Enable='off';
                  end
                  
                 if isnumeric(app.input.VPP) && app.input.VPP>0 %check voltage limit
                     app.VPPknob.Value=app.input.VPP; 
                 else
                     app.VPPknob.Enable='off';
                     app.VPPlamp.Enable ='off';
                     app.AutoEMERGENCYstop('Wrong Input');    
                     app.STOP.Enable='off';
                 end
                if isnumeric(app.input.VPPc) && app.input.VPPc>0 && app.input.VPPc<375.01 %check current limit
                 else
                     app.VPPknob.Enable='off';
                     app.VPPlamp.Enable ='off';
                     app.AutoEMERGENCYstop('Wrong Input');    
                     app.STOP.Enable='off';
                end
                if isnumeric(app.input.VNNc) && app.input.VNNc>0 && app.input.VNNc<375.01 %check current limit
                 else
                     app.VNNknob.Enable='off';
                     app.VNNlamp.Enable ='off';
                     app.AutoEMERGENCYstop('Wrong Input');    
                     app.STOP.Enable='off';
                 end
                  
                  
              else                       % means we already setup CCU, setup based on DataRev
                 
                     
               if app.DataRev.VNNen==0
                   app.VNNlamp.Color=[0.9 0.9 0.9];
               else
                   app.VNNlamp.Color='g' ;
               end
               if app.DataRev.VPPen==0
                   app.VPPlamp.Color=[0.9 0.9 0.9];
               else
                   app.VPPlamp.Color='g' ;
               end 
               if app.DataRev.Trigger2==1
               app.VNNread.Value=app.DataRev.VNNccu;
               if app.DataRev.VNNccu >app.input.VNN-app.input.VoltageTolerance && app.DataRev.VNNccu <app.input.VNN+app.input.VoltageTolerance
                    app.VNNread.BackgroundColor='g';
               elseif app.DataRev.VNNccu>-5
                   app.VNNread.BackgroundColor='b'; 
               else
                   app.VNNread.BackgroundColor='r';
               end    
               app.VPPread.Value=app.DataRev.VPPccu; 
               if app.DataRev.VPPccu >app.input.VPP-app.input.VoltageTolerance && app.DataRev.VPPccu <app.input.VPP+app.input.VoltageTolerance
                    app.VPPread.BackgroundColor='g';
               elseif app.DataRev.VPPccu<5
                   app.VPPread.BackgroundColor='b'; 
               else
                   app.VPPread.BackgroundColor='r';
               end
               
               app.Card1VNN.Value=app.DataRev.VNNc1; 
               if app.DataRev.VNNc1 >app.input.VNN-app.input.VoltageTolerance && app.DataRev.VNNc1 <app.input.VNN+app.input.VoltageTolerance
                    app.Card1VNN.BackgroundColor='g';
               elseif app.DataRev.VNNc1>-5
                   app.Card1VNN.BackgroundColor='b';
               else
                   app.Card1VNN.BackgroundColor='r';
               end
               
               app.Card2VNN.Value=app.DataRev.VNNc2; 
               if app.DataRev.VNNc2 >app.input.VNN-app.input.VoltageTolerance && app.DataRev.VNNc2 <app.input.VNN+app.input.VoltageTolerance
                    app.Card2VNN.BackgroundColor='g';
               elseif app.DataRev.VNNc2>-5
                   app.Card2VNN.BackgroundColor='b';
               else
                   app.Card2VNN.BackgroundColor='r';
               end
               app.Card3VNN.Value=app.DataRev.VNNc3; 
               if app.DataRev.VNNc3 >app.input.VNN-app.input.VoltageTolerance && app.DataRev.VNNc3 <app.input.VNN+app.input.VoltageTolerance
                    app.Card3VNN.BackgroundColor='g';
               elseif app.DataRev.VNNc3>-5
                   app.Card3VNN.BackgroundColor='b';     
               else
                   app.Card3VNN.BackgroundColor='r';
               end
               app.Card4VNN.Value=app.DataRev.VNNc4; 
               if app.DataRev.VNNc4 >app.input.VNN-app.input.VoltageTolerance && app.DataRev.VNNc4 <app.input.VNN+app.input.VoltageTolerance
                    app.Card4VNN.BackgroundColor='g';
               elseif app.DataRev.VNNc4>-5
                   app.Card4VNN.BackgroundColor='b';     
               else
                   app.Card4VNN.BackgroundColor='r';
               end
               
               app.Card1VPP.Value=app.DataRev.VPPc1; 
               if app.DataRev.VPPc1 >app.input.VPP-app.input.VoltageTolerance && app.DataRev.VPPc1 <app.input.VPP+app.input.VoltageTolerance
                    app.Card1VPP.BackgroundColor='g';
               elseif app.DataRev.VPPc1<5
                   app.Card1VPP.BackgroundColor='b';     
               else
                   app.Card1VPP.BackgroundColor='r';
               end
               
               app.Card2VPP.Value=app.DataRev.VPPc2; 
               if app.DataRev.VPPc2 >app.input.VPP-app.input.VoltageTolerance && app.DataRev.VPPc2 <app.input.VPP+app.input.VoltageTolerance
                    app.Card2VPP.BackgroundColor='g';
               elseif app.DataRev.VPPc2<5
                   app.Card2VPP.BackgroundColor='b';
               else
                   app.Card2VPP.BackgroundColor='r';
               end
               app.Card3VPP.Value=app.DataRev.VPPc3; 
               if app.DataRev.VPPc3 >app.input.VPP-app.input.VoltageTolerance && app.DataRev.VPPc3 <app.input.VPP+app.input.VoltageTolerance
                    app.Card3VPP.BackgroundColor='g';
               elseif app.DataRev.VPPc3<5
                   app.Card3VPP.BackgroundColor='b';
               else
                   app.Card3VPP.BackgroundColor='r';
               end
               app.Card4VPP.Value=app.DataRev.VPPc4; 
               if app.DataRev.VPPc4 >app.input.VPP-app.input.VoltageTolerance && app.DataRev.VPPc4 <app.input.VPP+app.input.VoltageTolerance
                    app.Card4VPP.BackgroundColor='g';
               elseif app.DataRev.VPPc4<5
                   app.Card4VPP.BackgroundColor='b';
               else
                   app.Card4VPP.BackgroundColor='r';
               end
               else
               app.VNNread.Value=0;

                   app.VNNread.BackgroundColor='r';
     
               app.VPPread.Value=0; 

                   app.VPPread.BackgroundColor='r';

               
               app.Card1VNN.Value=0'; 

                   app.Card1VNN.BackgroundColor='r';
       
               
               app.Card2VNN.Value=0; 

                   app.Card2VNN.BackgroundColor='r';
      
               app.Card3VNN.Value=0; 

                   app.Card3VNN.BackgroundColor='r';
    
               app.Card4VNN.Value=0; 

                   app.Card4VNN.BackgroundColor='r';
   
               
               app.Card1VPP.Value=0; 

                   app.Card1VPP.BackgroundColor='r';

               app.Card2VPP.Value=0; 

                   app.Card2VPP.BackgroundColor='r';

               app.Card3VPP.Value=0; 

                   app.Card3VPP.BackgroundColor='r';
             
               app.Card4VPP.Value=0; 
               app.Card4VPP.BackgroundColor='r';
               
               end
                   
                   %%
               if app.DataRev.Card1Con==0
                   app.Card1Connect.Color='r';
               else
                   app.Card1Connect.Color='g';
               end
               if app.DataRev.Card2Con==0
                   app.Card2Connect.Color='r';
               else
                   app.Card2Connect.Color='g';
               end
               if app.DataRev.Card3Con==0
                   app.Card3Connect.Color='r';
               else
                   app.Card3Connect.Color='g';
               end
               if app.DataRev.Card4Con==0
                   app.Card4Connect.Color='r';
               else
                   app.Card4Connect.Color='g';
               end
%                if app.DataRev.Delay1==0
%                      app.Delay1.Value = 'Disbaled';  
%                      app.Delay1.BackgroundColor=[0.9 0.9 0.9]; 
%                else
%                      app.Delay1.Value = 'qSwitch';  
%                      app.Delay1.BackgroundColor='g'; 
%                end
%                if app.DataRev.Delay2==0
%                      app.Delay2.Value = 'Disbaled';  
%                      app.Delay2.BackgroundColor=[0.9 0.9 0.9]; 
%                else
%                      app.Delay2.Value = 'Laser 10 Hz';  
%                      app.Delay2.BackgroundColor='g'; 
%                end
%                if app.DataRev.Delay3==0
%                      app.Delay3.Value = 'Disbaled';  
%                      app.Delay3.BackgroundColor=[0.9 0.9 0.9]; 
%                else
%                      app.Delay3.Value = 'PD 10 Hz';  
%                      app.Delay3.BackgroundColor='g'; 
%                end
%                if app.DataRev.Delay4==0
%                      app.Delay4.Value = 'Disbaled';  
%                      app.Delay4.BackgroundColor=[0.9 0.9 0.9]; 
%                else
%                      app.Delay4.Value = 'Connect TrI1';  
%                      app.Delay4.BackgroundColor='y'; 
%                end


              end
              
          end
          
          
          function PerformSafetyCheck(app) %this function will perform a check on all values and if there is a problem will stop CCU
              
              
          end
          
          function AutoEMERGENCYstop (app,ErrorMSG)
              if app.timerstatus==1
              stop(app.timr);
              app.timerstatus=0;
              end
                app.STOP.Text="Error";
                app.STOP.FontColor='c';
                app.input.STOP=1;                     % STOP NOW
                app.DataRev = COMfunction(app.input); % excure STOP on CCU  
                display(ErrorMSG);
                app.STOP.Value=1;

          end
        
        function shapedata(app)
            if app.input.ImgMode=='US'
             app.input.Trigger1=1;  % Trig1 enable
             app.input.Trigger2=0;  % Trig2 enable 
            elseif app.input.ImgMode=='PA'
             app.input.Trigger1=1;  % Trig1 enable
             app.input.Trigger2=1;  % Trig2 enable  
              app.input.delay2.En=1;  % Enable Out
             app.input.delay2.IniD=0; %delay to be come active after Tri
             app.input.delay2.lengthD=2000; %ns on or off state
             app.input.delay2.Inv=0; %Invert logic state
             app.input.delay3.En=1;  % Enable Out
             app.input.delay3.IniD=0; %delay to be come active after Tri
             app.input.delay3.lengthD=2000; %ns on or off state
             app.input.delay3.Inv=0; %Invert logic state
             app.input.delay4.En=1;  % Enable Out
             app.input.delay4.IniD=0; %delay to be come active after Tri
             app.input.delay4.lengthD=2000; %ns on or off state
             app.input.delay4.Inv=3; %Invert logic state
            else
            app.AutoEMERGENCYstop;
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, iInput)
             %  clc;
               app.input = iInput;             %<<<< needs to be activated
               app.input.intiation=0; %this variable is zero at the begining and will be non zero after initializing the CCU, if it is not zero, this means only receive update from CCU board 
               app.input.STOP=0;      % if this is 1, the board will perform emergency stop
               app.input.Operation=0;
               if  app.input.TimerInterval<0.250
                    app.input.TimerInterval=0.25;
               end
               if  app.input.TimerInterval>1
                    app.input.TimerInterval=1;
               end
 app.input.delay2.En=0;  % Enable Out
 app.input.delay2.IniD=0; %delay to be come active after Tri
 app.input.delay2.lengthD=2000; %ns on or off state
 app.input.delay2.Inv=0; %Invert logic state
 app.input.delay3.En=0;  % Enable Out
 app.input.delay3.IniD=0; %delay to be come active after Tri
 app.input.delay3.lengthD=2000; %ns on or off state
 app.input.delay3.Inv=0; %Invert logic state
 app.input.delay4.En=0;  % Enable Out
 app.input.delay4.IniD=0; %delay to be come active after Tri
 app.input.delay4.lengthD=2000; %ns on or off state
 app.input.delay4.Inv=3; %Invert logic state
app.input.STOP=1;
 app.input.Trigger1=0;  % Trig1 enable
 app.input.Trigger2=0;  % Trig2 enable  
 
  display('Central Control Unit is initializing...');
  app.MSG.Text='initializing...';

 
          app.UIFigure.Name="CCU V1.0 - Develouped by: Afshin K. I.";    
          app.shapedata;
          app.repeatingTimer;    % starts timer at startup

        end

        % Value changed function: STOP
        function STOPValueChanged(app, event)
            value = app.STOP.Value;
            if value==0;
                %start(app.timr);

                app.input.STOP=0;                     % resume where we left
                app.DataRev = COMfunction(app.input); % excure resume on CCU
             
              
            else
                %stop(app.timr);

                app.input.STOP=1;                     % STOP NOW
                app.DataRev = COMfunction(app.input); % excure STOP on CCU
              
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
              if app.timerstatus==1
              stop(app.timr);
              app.timerstatus=0;
              end
%               if app.input.intiation==1
%               app.input.STOP=1; 
%               app.DataRev = COMfunction(app.input);
%               end
                newobjs = instrfind;
                fclose(newobjs)

            delete(app)  

        end

        % Value changed function: VNNknob
        function VNNknobValueChanged(app, event)
              if app.timerstatus==1
              stop(app.timr);
              app.timerstatus=0;
              end
            value = app.VNNknob.Value;
            
                
%             app.input.Operation=1;
            app.input.VNN=value;
            if app.Switch.Value=='1'
            app.input.VPP=abs(value);  
            app.VPPknob.Value=abs(value);
            end
            app.input.STOP=1;
%             app.DataRev = COMfunction(app.input); % excure STOP on CCU
%             app.input.Operation=0;         
               if app.timerstatus==0
              start(app.timr);
              app.timerstatus=1;
              end
            
        end

        % Value changed function: VPPknob
        function VPPknobValueChanged(app, event)
            value = app.VPPknob.Value;
              if app.timerstatus==1
              stop(app.timr);
              app.timerstatus=0;
              end
            
%             app.input.Operation=1;
            app.input.VPP=value;
            if app.Switch.Value=='1'
            app.input.VNN=-1*value;  
            app.VNNknob.Value=-1*value;
            end
            app.input.STOP=1;
%             app.DataRev = COMfunction(app.input); % excure STOP on CCU
         %   app.input.Operation=0;   
         
               if app.timerstatus==0
              start(app.timr);
              app.timerstatus=1;
              end
        end

        % Button pushed function: OnlyreprogramPSsButton
        function OnlyreprogramPSsButtonPushed(app, event)
            app.input.Operation=1;
        end

        % Button pushed function: ReprogramButtonall
        function ReprogramButtonallPushed(app, event)
          app.input.intiation=0;  
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DelayGeneratorStatusPanel = uipanel(app.UIFigure);
            app.DelayGeneratorStatusPanel.Title = 'Delay Generator Status';
            app.ImagingModeEditFieldLabel = uilabel(app.DelayGeneratorStatusPanel);
            app.ImagingModeEditFieldLabel.Text = 'Imaging Mode';
            app.ImagingMode = uieditfield(app.DelayGeneratorStatusPanel, 'text');
            app.ImagingMode.Editable = 'off';
            app.ImagingMode.Value = 'Ultrasound';
            app.DelayGenerationLabel = uilabel(app.DelayGeneratorStatusPanel);
            app.DelayGenerationLabel.Text = 'Delay Generation';
            app.WaveGen = uieditfield(app.DelayGeneratorStatusPanel, 'text');
            app.WaveGen.Editable = 'off';
            app.WaveGen.Value = 'Disabled';
            app.HzPortLabel = uilabel(app.DelayGeneratorStatusPanel);
            app.HzPortLabel.Text = '10 Hz Port';
            app.Frequency = uieditfield(app.DelayGeneratorStatusPanel, 'text');
            app.Frequency.Editable = 'off';
            app.Frequency.Value = 'Disabled';
            app.IO1PortLabel = uilabel(app.DelayGeneratorStatusPanel);
            app.IO1PortLabel.Text = 'IO 1 Port';
            app.Delay1 = uieditfield(app.DelayGeneratorStatusPanel, 'text');
            app.Delay1.Editable = 'off';
            app.Delay1.Value = 'Disabled';
            app.IO2PortLabel = uilabel(app.DelayGeneratorStatusPanel);
            app.IO2PortLabel.Text = 'IO 2 Port';
            app.Delay2 = uieditfield(app.DelayGeneratorStatusPanel, 'text');
            app.Delay2.Editable = 'off';
            app.Delay2.Value = 'Disabled';
            app.IO3PortLabel = uilabel(app.DelayGeneratorStatusPanel);
            app.IO3PortLabel.Text = 'IO 3 Port';
            app.Delay3 = uieditfield(app.DelayGeneratorStatusPanel, 'text');
            app.Delay3.Editable = 'off';
            app.Delay3.Value = 'Disabled';
            app.IO4PortLabel = uilabel(app.DelayGeneratorStatusPanel);
            app.IO4PortLabel.Text = 'IO 4 Port';
            app.Delay4 = uieditfield(app.DelayGeneratorStatusPanel, 'text');
            app.Delay4.Editable = 'off';
            app.Delay4.Value = 'TrO';
            app.TriggerOutDelayLabel = uilabel(app.DelayGeneratorStatusPanel);
            app.TriggerOutDelayLabel.Text = 'Trigger Out Delay';
            app.TriggerOutDly = uieditfield(app.DelayGeneratorStatusPanel, 'text');
            app.TriggerOutDly.Editable = 'off';
            app.TriggerOutDly.Value = 'Disabled';
            app.TriggerIN1EditFieldLabel = uilabel(app.DelayGeneratorStatusPanel);
            app.TriggerIN1EditFieldLabel.Text = 'Trigger IN 1';
            app.TriggerIN1 = uieditfield(app.DelayGeneratorStatusPanel, 'text');
            app.TriggerIN1.Editable = 'off';
            app.TriggerIN1.Value = 'Tri IN VSX';
            app.TriggerIN2EditFieldLabel = uilabel(app.DelayGeneratorStatusPanel);
            app.TriggerIN2EditFieldLabel.Text = 'Trigger IN 2';
            app.TriggerIN2 = uieditfield(app.DelayGeneratorStatusPanel, 'text');
            app.TriggerIN2.Editable = 'off';
            app.TriggerIN2.Value = 'BACKUP';
            app.HighVoltagePowerSuppliesPanel = uipanel(app.UIFigure);
            app.HighVoltagePowerSuppliesPanel.Title = 'High Voltage Power Supplies';
            app.VNNknob = uiknob(app.HighVoltagePowerSuppliesPanel, 'continuous');
            app.VNNknob.Limits = [-250 0];
            app.VNNknob.ValueChangedFcn = createCallbackFcn(app, @VNNknobValueChanged, true);
            app.VNNLabel = uilabel(app.HighVoltagePowerSuppliesPanel);
            app.VNNLabel.Text = 'VNN';
            app.VNNlamp = uilamp(app.HighVoltagePowerSuppliesPanel);
            app.VPPknob = uiknob(app.HighVoltagePowerSuppliesPanel, 'continuous');
            app.VPPknob.Limits = [0 250];
            app.VPPknob.ValueChangedFcn = createCallbackFcn(app, @VPPknobValueChanged, true);
            app.VPPLabel = uilabel(app.HighVoltagePowerSuppliesPanel);
            app.VPPLabel.Text = 'VPP';
            app.VPPlamp = uilamp(app.HighVoltagePowerSuppliesPanel);
            app.VNNreadVEditField_2Label = uilabel(app.HighVoltagePowerSuppliesPanel);
            app.VNNreadVEditField_2Label.Text = 'VNN read (V)';
            app.VNNread = uieditfield(app.HighVoltagePowerSuppliesPanel, 'numeric');
            app.VNNread.Editable = 'off';
            app.VPPreadVEditFieldLabel = uilabel(app.HighVoltagePowerSuppliesPanel);
            app.VPPreadVEditFieldLabel.Text = 'VPP read (V)';
            app.VPPread = uieditfield(app.HighVoltagePowerSuppliesPanel, 'numeric');
            app.VPPread.Editable = 'off';
            app.Switch = uiswitch(app.HighVoltagePowerSuppliesPanel, 'slider');
            app.Switch.Items = {'0', '1'};
            app.Switch.Value = '0';
            app.ParallelLabel = uilabel(app.HighVoltagePowerSuppliesPanel);
            app.ParallelLabel.Text = 'Parallel';
            app.STOP = uibutton(app.UIFigure, 'state');
            app.STOP.ValueChangedFcn = createCallbackFcn(app, @STOPValueChanged, true);
            app.STOP.Text = 'STOP';
            app.STOP.Value = true;
            app.PanelCardsStatus = uipanel(app.UIFigure);
            app.PanelCardsStatus.Title = 'Cards Status';
            app.PanelCard1 = uipanel(app.PanelCardsStatus);
            app.Card1Label = uilabel(app.PanelCard1);
            app.Card1Label.Text = 'Card 1';
            app.Card1Connect = uilamp(app.PanelCard1);
            app.Card1VNN = uieditfield(app.PanelCard1, 'numeric');
            app.Card1VNN.Editable = 'off';
            app.Card1VPP = uieditfield(app.PanelCard1, 'numeric');
            app.Card1VPP.Editable = 'off';
            app.ConnectionLabel = uilabel(app.PanelCardsStatus);
            app.ConnectionLabel.Text = 'Connection';
            app.VNNLabel_2 = uilabel(app.PanelCardsStatus);
            app.VNNLabel_2.Text = 'VNN';
            app.PanelCard2 = uipanel(app.PanelCardsStatus);
            app.Card2Label = uilabel(app.PanelCard2);
            app.Card2Label.Text = 'Card 2';
            app.Card2Connect = uilamp(app.PanelCard2);
            app.Card2VNN = uieditfield(app.PanelCard2, 'numeric');
            app.Card2VNN.Editable = 'off';
            app.Card2VPP = uieditfield(app.PanelCard2, 'numeric');
            app.Card2VPP.Editable = 'off';
            app.VPPLabel_2 = uilabel(app.PanelCardsStatus);
            app.VPPLabel_2.Text = 'VPP';
            app.PanelCard3 = uipanel(app.PanelCardsStatus);
            app.Card3Label = uilabel(app.PanelCard3);
            app.Card3Label.Text = 'Card 3';
            app.Card3Connect = uilamp(app.PanelCard3);
            app.Card3VNN = uieditfield(app.PanelCard3, 'numeric');
            app.Card3VNN.Editable = 'off';
            app.Card3VPP = uieditfield(app.PanelCard3, 'numeric');
            app.Card3VPP.Editable = 'off';
            app.PanelCard4 = uipanel(app.PanelCardsStatus);
            app.Card4Label = uilabel(app.PanelCard4);
            app.Card4Label.Text = 'Card 4';
            app.Card4Connect = uilamp(app.PanelCard4);
            app.Card4VNN = uieditfield(app.PanelCard4, 'numeric');
            app.Card4VNN.Editable = 'off';
            app.Card4VPP = uieditfield(app.PanelCard4, 'numeric');
            app.Card4VPP.Editable = 'off';
            app.MSG = uilabel(app.UIFigure);
            app.MSG.Text = 'test';
            app.OnlyreprogramPSsButton = uibutton(app.UIFigure, 'push');
            app.OnlyreprogramPSsButton.ButtonPushedFcn = createCallbackFcn(app, @OnlyreprogramPSsButtonPushed, true);
            app.OnlyreprogramPSsButton.Text = 'Only re-program PSs';
            app.ReprogramButtonall = uibutton(app.UIFigure, 'push');
            app.ReprogramButtonall.ButtonPushedFcn = createCallbackFcn(app, @ReprogramButtonallPushed, true);
            app.ReprogramButtonall.Text = 'Restart everything';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CCU(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end